classdef ProyectoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnidaddeEfectosdeAudioUIFigure  matlab.ui.Figure
        EfectosPanel_3                  matlab.ui.container.Panel
        EcoCheckBox                     matlab.ui.control.CheckBox
        TremoloCheckBox                 matlab.ui.control.CheckBox
        FlangerCheckBox                 matlab.ui.control.CheckBox
        EfectoCheckBox_5                matlab.ui.control.CheckBox
        EfectoCheckBox_6                matlab.ui.control.CheckBox
        SecLabel                        matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        AplicarEfecto1                  matlab.ui.control.Button
        ReproducirEfecto1               matlab.ui.control.Button
        LampEfecto1                     matlab.ui.control.Lamp
        Slider_2                        matlab.ui.control.Slider
        HzLabel_5                       matlab.ui.control.Label
        Slider_3                        matlab.ui.control.Slider
        ArchivoEntradaPanel             matlab.ui.container.Panel
        ExaminarButton_2                matlab.ui.control.Button
        ReproducirarchivoButton         matlab.ui.control.Button
        ArchivoAudioLabel               matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        DuracionLabel                   matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        DurationLabel                   matlab.ui.control.Label
        CanalesLabel                    matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        NumCanalesLabel                 matlab.ui.control.Label
        Banda1Panel                     matlab.ui.container.Panel
        FreqaltaEditFieldLabel          matlab.ui.control.Label
        FreqaltaEditField               matlab.ui.control.NumericEditField
        FreqbajaEditFieldLabel          matlab.ui.control.Label
        FreqbajaEditField               matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        AplicarBanda1                   matlab.ui.control.Button
        LampBanda1                      matlab.ui.control.Lamp
        EfectosPanel_2                  matlab.ui.container.Panel
        EcoCheckBox_2                   matlab.ui.control.CheckBox
        TremoloCheckBox_2               matlab.ui.control.CheckBox
        FlangerCheckBox_2               matlab.ui.control.CheckBox
        EfectoCheckBox_7                matlab.ui.control.CheckBox
        EfectoCheckBox_8                matlab.ui.control.CheckBox
        Slider_4                        matlab.ui.control.Slider
        Slider_5                        matlab.ui.control.Slider
        Slider_6                        matlab.ui.control.Slider
        AplicarEfecto2                  matlab.ui.control.Button
        ReproducirEfecto2               matlab.ui.control.Button
        LampEfecto2                     matlab.ui.control.Lamp
        GananciaPanel                   matlab.ui.container.Panel
        GananciadefiltrosSlider         matlab.ui.control.Slider
        AplicarGanancia                 matlab.ui.control.Button
        ReproducirGanancia              matlab.ui.control.Button
        LampGanancia                    matlab.ui.control.Lamp
        Banda2Panel                     matlab.ui.container.Panel
        FreqaltaEditField_2Label        matlab.ui.control.Label
        FreqaltaEditField_2             matlab.ui.control.NumericEditField
        FreqbajaEditField_2Label        matlab.ui.control.Label
        FreqbajaEditField_2             matlab.ui.control.NumericEditField
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        AplicarBanda2                   matlab.ui.control.Button
        LampBanda2                      matlab.ui.control.Lamp
        EspectroArchivoEntradaPanel     matlab.ui.container.Panel
        EspectroEntradaAxes             matlab.ui.control.UIAxes
        EspectroEfectoPanel             matlab.ui.container.Panel
        EspectroEfectoAxes              matlab.ui.control.UIAxes
        EfectoDropDownLabel             matlab.ui.control.Label
        EfectoDropDown                  matlab.ui.control.DropDown
        EspectroArchivoSalidaPanel      matlab.ui.container.Panel
        EspectroSalidaAxes              matlab.ui.control.UIAxes
        ArchivoSalidaPanel              matlab.ui.container.Panel
        ExaminarButton_3                matlab.ui.control.Button
        GuardarButton                   matlab.ui.control.Button
        ArchivoAudioLabel_2             matlab.ui.control.Label
    end


    methods (Access = private)
    
        function plotSpectrum(app, axes, data, Fs)
            
            N = Fs;

            XT = abs(fft(data, N)/N);

            XP = XT(1:N/2+1);
            XP(2:end-1) = 2*XP(2:end-1);

            f = Fs*(0:(N/2))/N;
                
            plot(axes, f(1:20000), XP(1:20000));
            
        end
        
        function y = bandPass(app, x, Fl, Fh, Fs)
            
            N = 201;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);


            fcl = Fh/Fs;
            h_low = 2*fcl*sinc(2*fcl*n)' .* w;

            fch = Fl/Fs;
            h_high = (sinc(n) - 2*fch*sinc(2*fch*n))' .* w;

            
            y_low = filter(h_low, [1], x);
            y_band = filter(h_high, [1], y_low);
            y = y_band;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExaminarButton_2
        function ExaminarButton_2Pushed(app, event)
            global inputPath;
            global inputFile;
            global fileSelected;
            global songData;
            global Fs;
            global channels;
            fileSelected = 0;
            [inputFile, inputPath] = uigetfile(".wav");
            if isequal(inputFile,0)
                % not file
                app.ArchivoAudioLabel.Text = "<Archivo Audio>";                
                fileSelected = 0;
            else
                % file selected
                app.ArchivoAudioLabel.Text = inputFile;
                fileSelected = 1;
                
                % load audio data
                [songData, Fs] = audioread(join([inputPath inputFile], ""));
                
                % cantidad de canales
                channels = size(songData,2);
                app.NumCanalesLabel.Text = string(channels);
                
                % mostrar duracion
                duration = size(songData, 1)/Fs;
                min = floor(duration/60);
                secs = duration - min*60;
                app.DurationLabel.Text = sprintf("%02.0f:%05.2f", min, secs);
                
                % graficar espectro entrada
                app.plotSpectrum(app.EspectroEntradaAxes, songData, Fs);
            end
            
        end

        % Button pushed function: ReproducirarchivoButton
        function ReproducirarchivoButtonPushed(app, event)
            global songData;
            global Fs;
            global fileSelected;
            if isequal(fileSelected, 1)
                soundsc(songData, Fs);
            end
        end

        % Value changing function: GananciadefiltrosSlider
        function GananciadefiltrosSliderValueChanging(app, event)
            global gainValue;
            gainValue = event.Value;            
        end

        % Value changed function: FreqaltaEditField
        function FreqaltaEditFieldValueChanged(app, event)
            global band1HF;
            band1HF = app.FreqaltaEditField.Value;
            app.LampBanda1.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqbajaEditField
        function FreqbajaEditFieldValueChanged(app, event)
            global band1LF;
            band1LF = app.FreqbajaEditField.Value;
            app.LampBanda1.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqaltaEditField_2
        function FreqaltaEditField_2ValueChanged(app, event)
            global band2HF;
            band2HF = app.FreqaltaEditField_2.Value;
            app.LampBanda2.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqbajaEditField_2
        function FreqbajaEditField_2ValueChanged(app, event)
            global band2LF;
            band2LF = app.FreqbajaEditField_2.Value;
            app.LampBanda2.Color = [0.64 0.08 0.18];
            
        end

        % Button pushed function: AplicarBanda1
        function AplicarBanda1ButtonPushed(app, event)
            global songData;
            global band1LF;
            global band1HF;
            global Fs;
            global band1Data;
            band1Data = app.bandPass(songData, band1LF, band1HF, Fs);
            app.LampBanda1.Color = [0.47 0.67 0.19];
            
            % for debug
            %app.plotSpectrum(app.EspectroSalidaAxes, band1Data, Fs);
        end

        % Button pushed function: AplicarBanda2
        function AplicarBanda2ButtonPushed(app, event)
            global songData;
            global band2LF;
            global band2HF;
            global Fs;
            global band2Data;
            band2Data = app.bandPass(songData, band2LF, band2HF, Fs);
            app.LampBanda2.Color = [0.47 0.67 0.19];
            
            % for debug
            %app.plotSpectrum(app.EspectroSalidaAxes, band2Data, Fs);
        end

        % Value changed function: TremoloCheckBox
        function TremoloCheckBoxValueChanged(app, event)
            global tremolo;
            tremolo = app.TremoloCheckBox.Value;
        end

        % Button pushed function: AplicarEfecto1
        function AplicarEfecto1ButtonPushed(app, event)
            global songData;
            global Fs;
            global tremoloSong;
            global flangerSong;
            global tremolo;
            global tremoloValue;
            global flanger;
            global flangerValue;

            % Parámetros utiles para el proceso de audio
            songLength = length(songData);
            t = ((0:songLength-1)/Fs)';
            
            % Aplicar tremolo
            if (tremolo == 1)
                sigAux = cos(2*pi*tremoloValue*t);
                tremoloSong = songData .* sigAux;
            else 
                sigAux = 1;
                tremoloSong= songData * sigAux;
            end
            
            % aplicar Flanger
            if (flanger == 1)
                maxDelay = 0.003; % 3ms max delayin seconds
                amp = 0.7; % suggested coefficient from page 71 DAFX
                rate = flangerValue; % oscilaction in Hz
                
                % crear señales de control
                index=1:length(songData);
                sigRef = (sin(2*pi*index*(rate/Fs)))';    % sin(2pi*fa/fs);
                maxSampDelay = round(maxDelay*Fs); %convert delay in ms to max delay in samples
                y = zeros(length(songData),1);       % create empty out vector
                y(1:maxSampDelay) = songData(1:maxSampDelay); % to avoid referencing of negative samples
                
                % Procesando Audio
                for i = (maxSampDelay+1):length(songData)
                    actualSig=abs(sigRef(i));    %abs of current sin val 0-1
                    actualDelay=ceil(actualSig*maxSampDelay);  % generate delay from 1-max_samp_delay and ensure whole number
                    y(i) = (amp*songData(i)) + amp*(songData(i-actualDelay));   % add delayed sample
                end
                
                % Normalizando                    
                if (abs(max(y)) > abs(min(y)))
                    y = y./abs(max(y));
                else
                    y = y./abs(min(y));
                end
                    
                flangerSong = y;
            else 
                sigAux = 1;
                flangerSong = songData * sigAux;
            end
            
        end

        % Button pushed function: ReproducirEfecto1
        function ReproducirEfecto1ButtonPushed(app, event)
            global songData;
            global tremoloSong;
            global flangerSong;
            global Fs;
            global fileSelected;
            if isequal(fileSelected, 1)
                soundsc(flangerSong, Fs);
            end
            
            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            global tremoloValue;
            tremoloValue = app.Slider_2.Value;
        end

        % Value changed function: FlangerCheckBox
        function FlangerCheckBoxValueChanged(app, event)
            global flanger
            flanger = app.FlangerCheckBox.Value;     
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            global flangerValue
            flangerValue = app.Slider_3.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnidaddeEfectosdeAudioUIFigure = uifigure('Visible', 'off');
            app.UnidaddeEfectosdeAudioUIFigure.Name = 'Unidad de Efectos de Audio';
            app.EfectosPanel_3 = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EfectosPanel_3.Title = 'Efectos';
            app.EcoCheckBox = uicheckbox(app.EfectosPanel_3);
            app.EcoCheckBox.Text = 'Eco';
            app.TremoloCheckBox = uicheckbox(app.EfectosPanel_3);
            app.TremoloCheckBox.ValueChangedFcn = createCallbackFcn(app, @TremoloCheckBoxValueChanged, true);
            app.TremoloCheckBox.Text = 'Tremolo';
            app.FlangerCheckBox = uicheckbox(app.EfectosPanel_3);
            app.FlangerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlangerCheckBoxValueChanged, true);
            app.FlangerCheckBox.Text = 'Flanger';
            app.EfectoCheckBox_5 = uicheckbox(app.EfectosPanel_3);
            app.EfectoCheckBox_5.Text = 'Efecto';
            app.EfectoCheckBox_6 = uicheckbox(app.EfectosPanel_3);
            app.EfectoCheckBox_6.Text = 'Efecto';
            app.AplicarEfecto1 = uibutton(app.EfectosPanel_3, 'push');
            app.AplicarEfecto1.ButtonPushedFcn = createCallbackFcn(app, @AplicarEfecto1ButtonPushed, true);
            app.AplicarEfecto1.Text = 'Aplicar';
            app.ReproducirEfecto1 = uibutton(app.EfectosPanel_3, 'push');
            app.ReproducirEfecto1.ButtonPushedFcn = createCallbackFcn(app, @ReproducirEfecto1ButtonPushed, true);
            app.ReproducirEfecto1.Text = 'Reproducir';
            app.LampEfecto1 = uilamp(app.EfectosPanel_3);
            app.Slider = uislider(app.EfectosPanel_3);
            app.SecLabel = uilabel(app.EfectosPanel_3);
            app.SecLabel.Text = 'Sec';
            app.Slider_2 = uislider(app.EfectosPanel_3);
            app.Slider_2.Limits = [0.5 5];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 0.5;
            app.HzLabel_5 = uilabel(app.EfectosPanel_3);
            app.HzLabel_5.Text = 'Hz';
            app.Slider_3 = uislider(app.EfectosPanel_3);
            app.Slider_3.Limits = [0.5 5];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Value = 0.5;
            app.ArchivoEntradaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.ArchivoEntradaPanel.Title = 'Archivo Entrada';
            app.ExaminarButton_2 = uibutton(app.ArchivoEntradaPanel, 'push');
            app.ExaminarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExaminarButton_2Pushed, true);
            app.ExaminarButton_2.Text = 'Examinar';
            app.ReproducirarchivoButton = uibutton(app.ArchivoEntradaPanel, 'push');
            app.ReproducirarchivoButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirarchivoButtonPushed, true);
            app.ReproducirarchivoButton.Text = 'Reproducir archivo';
            app.ArchivoAudioLabel = uilabel(app.ArchivoEntradaPanel);
            app.ArchivoAudioLabel.Text = '<Archivo Audio>';
            app.Panel_2 = uipanel(app.ArchivoEntradaPanel);
            app.DuracionLabel = uilabel(app.Panel_2);
            app.DuracionLabel.Text = 'Duracion';
            app.Panel_3 = uipanel(app.Panel_2);
            app.DurationLabel = uilabel(app.Panel_3);
            app.DurationLabel.Text = '00:00';
            app.CanalesLabel = uilabel(app.Panel_2);
            app.CanalesLabel.Text = 'Canales';
            app.Panel_4 = uipanel(app.Panel_2);
            app.NumCanalesLabel = uilabel(app.Panel_4);
            app.NumCanalesLabel.Text = '-';
            app.Banda1Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Banda1Panel.Title = 'Banda 1';
            app.FreqaltaEditFieldLabel = uilabel(app.Banda1Panel);
            app.FreqaltaEditFieldLabel.Text = 'Freq. alta';
            app.FreqaltaEditField = uieditfield(app.Banda1Panel, 'numeric');
            app.FreqaltaEditField.Limits = [0 20000];
            app.FreqaltaEditField.ValueChangedFcn = createCallbackFcn(app, @FreqaltaEditFieldValueChanged, true);
            app.FreqbajaEditFieldLabel = uilabel(app.Banda1Panel);
            app.FreqbajaEditFieldLabel.Text = 'Freq. baja';
            app.FreqbajaEditField = uieditfield(app.Banda1Panel, 'numeric');
            app.FreqbajaEditField.Limits = [0 20000];
            app.FreqbajaEditField.ValueChangedFcn = createCallbackFcn(app, @FreqbajaEditFieldValueChanged, true);
            app.HzLabel = uilabel(app.Banda1Panel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.Banda1Panel);
            app.HzLabel_2.Text = 'Hz';
            app.AplicarBanda1 = uibutton(app.Banda1Panel, 'push');
            app.AplicarBanda1.ButtonPushedFcn = createCallbackFcn(app, @AplicarBanda1ButtonPushed, true);
            app.AplicarBanda1.Text = 'Aplicar';
            app.LampBanda1 = uilamp(app.Banda1Panel);
            app.EfectosPanel_2 = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EfectosPanel_2.Title = 'Efectos';
            app.AplicarEfecto2 = uibutton(app.EfectosPanel_2, 'push');
            app.AplicarEfecto2.Text = 'Aplicar';
            app.ReproducirEfecto2 = uibutton(app.EfectosPanel_2, 'push');
            app.ReproducirEfecto2.Text = 'Reproducir';
            app.LampEfecto2 = uilamp(app.EfectosPanel_2);
            app.EcoCheckBox_2 = uicheckbox(app.EfectosPanel_2);
            app.EcoCheckBox_2.Text = 'Eco';
            app.TremoloCheckBox_2 = uicheckbox(app.EfectosPanel_2);
            app.TremoloCheckBox_2.Text = 'Tremolo';
            app.FlangerCheckBox_2 = uicheckbox(app.EfectosPanel_2);
            app.FlangerCheckBox_2.Text = 'Flanger';
            app.EfectoCheckBox_7 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_7.Text = 'Efecto';
            app.EfectoCheckBox_8 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_8.Text = 'Efecto';
            app.Slider_4 = uislider(app.EfectosPanel_2);
            app.Slider_5 = uislider(app.EfectosPanel_2);
            app.Slider_5.Limits = [0.5 5];
            app.Slider_5.Value = 0.5;
            app.Slider_6 = uislider(app.EfectosPanel_2);
            app.Slider_6.Limits = [0.5 5];
            app.Slider_6.Value = 0.5;
            app.GananciaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.GananciaPanel.Title = 'Ganancia';
            app.GananciadefiltrosSlider = uislider(app.GananciaPanel);
            app.GananciadefiltrosSlider.Limits = [0 1];
            app.GananciadefiltrosSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.GananciadefiltrosSlider.ValueChangingFcn = createCallbackFcn(app, @GananciadefiltrosSliderValueChanging, true);
            app.AplicarGanancia = uibutton(app.GananciaPanel, 'push');
            app.AplicarGanancia.Text = 'Aplicar';
            app.ReproducirGanancia = uibutton(app.GananciaPanel, 'push');
            app.ReproducirGanancia.Text = 'Reproducir';
            app.LampGanancia = uilamp(app.GananciaPanel);
            app.Banda2Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Banda2Panel.Title = 'Banda 2';
            app.FreqaltaEditField_2Label = uilabel(app.Banda2Panel);
            app.FreqaltaEditField_2Label.Text = 'Freq. alta';
            app.FreqaltaEditField_2 = uieditfield(app.Banda2Panel, 'numeric');
            app.FreqaltaEditField_2.Limits = [0 20000];
            app.FreqaltaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqaltaEditField_2ValueChanged, true);
            app.FreqbajaEditField_2Label = uilabel(app.Banda2Panel);
            app.FreqbajaEditField_2Label.Text = 'Freq. baja';
            app.FreqbajaEditField_2 = uieditfield(app.Banda2Panel, 'numeric');
            app.FreqbajaEditField_2.Limits = [0 20000];
            app.FreqbajaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqbajaEditField_2ValueChanged, true);
            app.HzLabel_3 = uilabel(app.Banda2Panel);
            app.HzLabel_3.Text = 'Hz';
            app.HzLabel_4 = uilabel(app.Banda2Panel);
            app.HzLabel_4.Text = 'Hz';
            app.AplicarBanda2 = uibutton(app.Banda2Panel, 'push');
            app.AplicarBanda2.ButtonPushedFcn = createCallbackFcn(app, @AplicarBanda2ButtonPushed, true);
            app.AplicarBanda2.Text = 'Aplicar';
            app.LampBanda2 = uilamp(app.Banda2Panel);
            app.EspectroArchivoEntradaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroArchivoEntradaPanel.Title = 'Espectro Archivo Entrada';
            app.EspectroEntradaAxes = uiaxes(app.EspectroArchivoEntradaPanel);
            xlabel(app.EspectroEntradaAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroEntradaAxes, 'Magnitud (dB)')
            app.EspectroEfectoPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroEfectoPanel.Title = 'Espectro Efecto';
            app.EspectroEfectoAxes = uiaxes(app.EspectroEfectoPanel);
            xlabel(app.EspectroEfectoAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroEfectoAxes, 'Magnitud (dB)')
            app.EfectoDropDownLabel = uilabel(app.EspectroEfectoPanel);
            app.EfectoDropDownLabel.Text = 'Efecto';
            app.EfectoDropDown = uidropdown(app.EspectroEfectoPanel);
            app.EspectroArchivoSalidaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroArchivoSalidaPanel.Title = 'Espectro Archivo Salida';
            app.EspectroSalidaAxes = uiaxes(app.EspectroArchivoSalidaPanel);
            xlabel(app.EspectroSalidaAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroSalidaAxes, 'Magnitud (dB)')
            app.ArchivoSalidaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.ArchivoSalidaPanel.Title = 'Archivo Salida';
            app.ExaminarButton_3 = uibutton(app.ArchivoSalidaPanel, 'push');
            app.ExaminarButton_3.Text = 'Examinar';
            app.GuardarButton = uibutton(app.ArchivoSalidaPanel, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.ArchivoAudioLabel_2 = uilabel(app.ArchivoSalidaPanel);
            app.ArchivoAudioLabel_2.Text = '<Archivo Audio>';
            % Show the figure after all components are created
            app.UnidaddeEfectosdeAudioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnidaddeEfectosdeAudioUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnidaddeEfectosdeAudioUIFigure)
        end
    end
end